classdef GUIAcclimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NoiseAmplitudeSpinner         matlab.ui.control.Spinner
        NoiseAmplitudeSpinnerLabel    matlab.ui.control.Label
        TimeresolutionSpinner         matlab.ui.control.Spinner
        TimeresolutionSpinnerLabel    matlab.ui.control.Label
        PlotallHeatmapsButton         matlab.ui.control.Button
        c3Spinner                     matlab.ui.control.Spinner
        c3SpinnerLabel                matlab.ui.control.Label
        c2Spinner                     matlab.ui.control.Spinner
        c2SpinnerLabel                matlab.ui.control.Label
        c1Spinner                     matlab.ui.control.Spinner
        c1SpinnerLabel                matlab.ui.control.Label
        ComputetotalerrorButton       matlab.ui.control.Button
        TrainingDurationSpinner       matlab.ui.control.Spinner
        TrainingDurationSpinnerLabel  matlab.ui.control.Label
        TrainingTempSpinner           matlab.ui.control.Spinner
        TrainingTempLabel             matlab.ui.control.Label
        HeatmapDeAcModel1             matlab.ui.control.UIAxes
        HeatmapDeAcExpt               matlab.ui.control.UIAxes
        HeatmapDeAcModel2             matlab.ui.control.UIAxes
        HeatmapExp                    matlab.ui.control.UIAxes
        HeatmapModel1                 matlab.ui.control.UIAxes
        HeatmapModel2                 matlab.ui.control.UIAxes
        UIAxesPlot                    matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Data = load('data.mat') % Description
        cmap = [];
        error = 0;
        %ODE solver variables
        x0 = 1;
        y0 = 1;
        c1 = 0.32;
        c2 = 0.38;
        c3 = 0.13;
        Model1Data = GenerateEmptyModelStructure();
        Model2Data = GenerateEmptyModelStructure();
        f = 1;
        NoiseAmplitude = 0.5;
    end
    
    methods (Access = public)
        
        function results = refresh(app)
            p = [];
            AnimalID = (app.TrainingTempSpinner.Value-2)/2;
            Training_Duration = app.TrainingDurationSpinner.Value;
            Training_Temperature = AnimalID*2+2;
            app.c1 = app.c1Spinner.Value;
            app.c2 = app.c2Spinner.Value;
            app.c3 = app.c3Spinner.Value;
            app.f = app.TimeresolutionSpinner.Value;
            app.NoiseAmplitude = app.NoiseAmplitudeSpinner.Value;
            Temperature = ([linspace(22,22,140-Training_Duration*10) linspace(Training_Temperature,Training_Temperature,Training_Duration*10) linspace(4,4,280)])./22;
            Temperature_Timepoints = linspace(0,length(Temperature)/10,length(Temperature));
            
            
            %Update Heatmaps - Acclimation       
            % app.Model1Data = GenerateEmptyModelStructure();
            % app.Model2Data = GenerateEmptyModelStructure();
            y0 = [app.x0 app.y0];            
            tspan = [linspace(0,14,15) linspace(15,43,29)]; %Time vectors for training and testing
            
            for theta = 1:length(app.Data.CstData3D(1,1,:))
                for D = 1:length(app.Data.CstData3D(1,:,1))                    
                    cs = [app.c1 app.c2 app.c3]; % No variability in coefficients
                    temperature1 = linspace(0,43,44*app.f);
                    temperature2 = [linspace(22,22,(15-(D-1))*app.f) linspace(theta*2+2,theta*2+2,(D-1)*app.f) linspace(4,4,29*app.f)]./22;
                    temperature = [temperature1; AddNoiseToTemp(temperature2,app.NoiseAmplitude)]; %Variability in temperature sensed
                    sol = ode45(@(t,y) Acclimfun(t,y,cs,temperature), tspan,y0);
                    solpts = deval(sol, tspan);
                    app.Model2Data.HealthFull(:,D,theta) = solpts(2,:);
                    app.Model2Data.HealthTruncated(:,D,theta) = solpts(2,linspace(15,43,29));
                    app.Model2Data.AlphaFull(:,D,theta) = solpts(1,:);
                    app.Model2Data.AlphaTruncated(:,D,theta) = solpts(1,linspace(15,43,29));

                    temperature = [temperature1; temperature2];
                    sol = ode45(@(t,y) Acclimfun(t,y,cs,temperature), tspan,y0);
                    solpts = deval(sol, tspan);
                    app.Model1Data.HealthFull(:,D,theta) = solpts(2,:);
                    app.Model1Data.HealthTruncated(:,D,theta) = solpts(2,linspace(15,43,29));
                    app.Model1Data.AlphaFull(:,D,theta) = solpts(1,:);
                    app.Model1Data.AlphaTruncated(:,D,theta) = solpts(1,linspace(15,43,29));                    
                end
            end
            imagesc(app.HeatmapModel1,squeeze(mean(app.Model1Data.HealthTruncated,1))')
            imagesc(app.HeatmapModel2,squeeze(mean(app.Model2Data.HealthTruncated,1))')
            imagesc(app.HeatmapExp,squeeze(mean(app.Data.CstData3D,1))')
            colormap(app.HeatmapModel1,app.Data.cmaplist.cmapRedBlue1);
            colormap(app.HeatmapModel2,app.Data.cmaplist.cmapRedBlue1);
            colormap(app.HeatmapExp,app.Data.cmaplist.cmapRedBlue1);

            %Update Heatmaps - DeAcclimation

            % Generate Deacclimation data            
            cs = [app.c1 app.c2 app.c3];
            tspan = 0:1:99;
            temperature1DeAc = linspace(0,99,100*app.f);
            health1 = [];
            health2 = [];
            alpha1 = [];
            alpha2 = [];

            % Acclimated - deaclimated animals
            for i = 1:28
                temperature2DeAc = [linspace(22,22,i*app.f)]./22; %Duration = 13 days, training temperatureDeAc = 10C
                temperature2DeAc = [temperature2DeAc linspace(4,4,length(temperature1DeAc)-length(temperature2DeAc))./22];
                temperatureDeAc2 = [temperature1DeAc; AddNoiseToTemp(temperature2DeAc,app.NoiseAmplitude)]; %AddNoiseToTemp(temperature2DeAc)
                y0 = [4/22 1]; %Start with a fully acclimated animal
                sol = ode45(@(t,y) Acclimfun(t,y,cs,temperatureDeAc2), tspan, y0);
                solpts = deval(tspan,sol);
                alpha2 = [alpha2; solpts(1,i:i+27)];
                health2 = [health2; solpts(2,i:i+27)];

                temperatureDeAc1 = [temperature1DeAc; temperature2DeAc];
                y0 = [4/22 1]; %Start with a fully acclimated animal
                sol = ode45(@(t,y) Acclimfun(t,y,cs,temperatureDeAc1), tspan, y0);
                solpts = deval(tspan,sol);
                alpha1 = [alpha1; solpts(1,i:i+27)];
                health1 = [health1; solpts(2,i:i+27)];
            end

            % Naive animals
            for i = 1:6
                temperature2DeAc = [linspace(22,22,0)]./22; %Duration = 13 days, training temperatureDeAc = 10C
                temperature2DeAc = [temperature2DeAc linspace(4,4,length(temperature1DeAc)-length(temperature2DeAc))./22];
                temperatureDeAc2 = [temperature1DeAc; AddNoiseToTemp(temperature2DeAc,app.NoiseAmplitude)]; %AddNoiseToTemp(temperature2DeAc)
                y0 = [1 1]; %Start with a fully deacclimated animal
                sol = ode45(@(t,y) Acclimfun(t,y,cs,temperatureDeAc2), tspan, y0);
                solpts = deval(tspan,sol);
                alpha2 = [alpha2; solpts(1,1:1+27)];
                health2 = [health2; solpts(2,1:1+27)];

                temperatureDeAc1 = [temperature1DeAc; temperature2DeAc];
                y0 = [1 1]; %Start with a fully deacclimated animal
                sol = ode45(@(t,y) Acclimfun(t,y,cs,temperatureDeAc1), tspan, y0);
                solpts = deval(tspan,sol);
                alpha1 = [alpha1; solpts(1,1:1+27)];
                health1 = [health1; solpts(2,1:1+27)];
            end
            imagesc(app.HeatmapDeAcModel1,health1);
            imagesc(app.HeatmapDeAcModel2,health2);            
            imagesc(app.HeatmapDeAcExpt,app.Data.Deacclimation);
            colormap(app.HeatmapDeAcModel1,app.Data.cmaplist.cmapRedBlue1);
            colormap(app.HeatmapDeAcModel2,app.Data.cmaplist.cmapRedBlue1);
            colormap(app.HeatmapDeAcExpt,app.Data.cmaplist.cmapRedBlue1);

            % Update main axes
            refreshPlot(app);
            save('csFromGui.mat', 'cs');
        end

        function results = refreshPlot(app)
            p = [];
            AnimalID = (app.TrainingTempSpinner.Value-2)/2;
            Training_Duration = app.TrainingDurationSpinner.Value;
            Training_Temperature = AnimalID*2+2;
            Temperature = ([linspace(22,22,140-Training_Duration*10) linspace(Training_Temperature,Training_Temperature,Training_Duration*10) linspace(4,4,280)])./22;
            Temperature_Timepoints = linspace(0,length(Temperature)/10,length(Temperature));
            % Update main axes
            p1 = plot(app.UIAxesPlot,0:1:(length(app.Model1Data.HealthFull(:,1,1))-1),app.Model1Data.HealthFull(:,Training_Duration+1,AnimalID),'Color',[app.cmap(2,:) 0.8],'LineWidth',2); hold(app.UIAxesPlot, 'on');
            p2 = plot(app.UIAxesPlot,0:1:(length(app.Model1Data.AlphaFull(:,1,1))-1),app.Model1Data.AlphaFull(:,Training_Duration+1,AnimalID),'Color',[app.cmap(1,:) 0.8],'LineWidth',2);
            p3 = plot(app.UIAxesPlot,Temperature_Timepoints, Temperature,'Color',[0 0 0 0.8],'LineWidth',2);
            p4 = plot(app.UIAxesPlot,linspace(14,14+28,29),app.Data.CstData3D(:,Training_Duration+1,AnimalID),'Color',[app.cmap(3,:) 0.8],'LineWidth',2);

            ax = app.UIAxesPlot; ax.LineWidth = 1.5; ax.FontSize = 16;
            ComputeError(app);

            %Axes properties
            ylim(app.UIAxesPlot, [-0.1 2.1]);
            text(app.UIAxesPlot,1,2,['Training Temp = ' num2str(Training_Temperature)], 'FontSize', 16);
            hold(app.UIAxesPlot, 'off');

            %Legend
            legend(app.UIAxesPlot, p, 'Health', 'Acclim level', 'Temperature', 'Raw Data', 'box', 'off');
        end
        
        function [t,y] = ModelData(app,AnimalID, Training_Duration)
            Training_Temperature = AnimalID*2+2;
            Temperature = ([linspace(22,22,140-Training_Duration*10) linspace(Training_Temperature,Training_Temperature,Training_Duration*10) linspace(4,4,280)])./22;
            Temperature_Timepoints = linspace(0,length(Temperature)/10,length(Temperature));
            tspan = [0 14+28];
            [t,y] = ode45(@(t,y) Acclimfun(t,y, [app.c1 app.c2 app.c3],[Temperature_Timepoints; Temperature]), tspan, [app.x0 app.y0]);
        end
        
        function results = ComputeError(app)
            AnimalID = (app.TrainingTempSpinner.Value-2)/2;
            Training_Duration = app.TrainingDurationSpinner.Value;
            Training_Temperature = AnimalID*2+2;

            ValuesRaw = app.Data.CstData3D(:,Training_Duration+1,AnimalID);            
            ValuesModel = app.Model1Data.HealthTruncated(:,Training_Duration+1,AnimalID);
            TimeModel = linspace(14,42,length(ValuesRaw));
            errortemp = sqrt(sum((ValuesRaw - ValuesModel).^2));            
            hold(app.UIAxesPlot, 'on');
            p5 = fill(app.UIAxesPlot, [TimeModel fliplr(TimeModel)], [ValuesModel' fliplr(ValuesRaw')], app.cmap(1,:));
            % p5 = fill(app.UIAxesPlot, [TimeModel fliplr(TimeModel)], [ValuesModel; ValuesRaw], app.cmap(1,:));
            % Plotting options
            p5.DisplayName = 'Error'; p5.FaceAlpha = 0.2; p5.EdgeColor = 'none';
            hold(app.UIAxesPlot, 'off');
            t = text (app.UIAxesPlot, 1, 1.75,{['error = ' num2str(errortemp,3)]}, 'FontSize', 16);
            results = errortemp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cmap = colormap(app.UIAxesPlot,'lines');
            app.Data.CstData3D = app.Data.CstData3D;
            refresh(app)
        end

        % Value changed function: TrainingTempSpinner
        function TrainingTempSpinnerValueChanged(app, event)
            %Refresh            
            refreshPlot(app);

        end

        % Value changed function: TrainingDurationSpinner
        function TrainingDurationSpinnerValueChanged(app, event)
            %Refresh
            refreshPlot(app);
        end

        % Button pushed function: ComputetotalerrorButton
        function ComputetotalerrorButtonPushed(app, event)
            app.error = 0;
            for i = 1:length(app.Data.CstData3D(1,1,:))
%             for i = app.TrainingTempSpinner.Value:app.TrainingTempSpinner.Value
                app.TrainingTempSpinner.Value = i; %To check progress
                for j = 0:length(app.Data.CstData3D(1,:,1))-1                
%                 for j = app.TrainingDurationSpinner.Value:app.TrainingDurationSpinner.Value
                    app.TrainingDurationSpinner.Value = j;
                    refresh(app)
                    errortemp = ComputeError(app);
                    app.error = app.error + errortemp;
                    text(app.UIAxesPlot, 10, 1.8,['total error = ' num2str(app.error)])
                end
            end
        end

        % Value changed function: c1Spinner
        function c1SpinnerValueChanged(app, event)
            %Refresh
            refresh(app);            
        end

        % Value changed function: c2Spinner
        function c2SpinnerValueChanged(app, event)
            %Refresh
            refresh(app);
        end

        % Value changed function: c3Spinner
        function c3SpinnerValueChanged(app, event)
            %Refresh
            refresh(app);            
        end

        % Button pushed function: PlotallHeatmapsButton
        function PlotallHeatmapsButtonPushed(app, event)
            figure('Name','Comparison Model vs Experiment','WindowState','maximized','Renderer','painters')
            tiledlayout(6,5)
            % Labels = ['BCDEFGHIJKLMNOPQ'];
            Labels = ['                '];
            % Experimental Data
            for i = [1 2 3 5 7]
                nexttile();
                h = heatmap(squeeze(app.Data.CstData3D(:,:,i))');
                h.ColorbarVisible = 'off'; %h.XLabel = 'T (Days)'; %h.YLabel = 'Training Duration';
                h.FontSize = 8;
                h.Title = [Labels(i) ' T= ' num2str(i*2+2) ' ^oC'];
                h.XDisplayLabels(:) = {''}; h.YDisplayLabels(:) = {''};
                % h.XDisplayLabels([1 5 10 15 20 25]) = {'0', '5', '10', '15', '20', '25'};
                % h.YDisplayLabels([1 6 11 15]) = {'0', '5', '10', '14'};
                % survival = [survival sum(CstData3D(:,:,i)>0.1,2);];
            end
            % Model Data (without noise)
            for i = [1 2 3 5 7]
                nexttile();
                h = heatmap(squeeze(app.Model1Data.HealthTruncated(:,:,i))');
                h.ColorbarVisible = 'off'; %h.XLabel = 'T (Days)'; %h.YLabel = 'Training Duration';
                h.FontSize = 8;
                % h.Title = [Labels(i) ' T= ' num2str(i*2+2) ' ^oC (model)'];
                h.XDisplayLabels(:) = {''}; h.YDisplayLabels(:) = {''};
                % h.XDisplayLabels([1 5 10 15 20 25]) = {'0', '5', '10', '15', '20', '25'};
                % h.YDisplayLabels([1 6 11 15]) = {'0', '5', '10', '14'};
                % survival = [survival sum(CstData3D(:,:,i)>0.1,2);];
            end
            % Model Data (with noise)
            for i = [1 2 3 5 7]
                nexttile();
                h = heatmap(squeeze(app.Model2Data.HealthTruncated(:,:,i))');
                h.ColorbarVisible = 'off'; %h.XLabel = 'T (Days)'; %h.YLabel = 'Training Duration';
                h.FontSize = 8;
                % h.Title = [Labels(i) ' T= ' num2str(i*2+2) ' ^oC (model)'];
                h.XDisplayLabels(:) = {''}; h.YDisplayLabels(:) = {''};
                % h.XDisplayLabels([1 5 10 15 20 25]) = {'0', '5', '10', '15', '20', '25'};
                % h.YDisplayLabels([1 6 11 15]) = {'0', '5', '10', '14'};
                % survival = [survival sum(CstData3D(:,:,i)>0.1,2);];
            end

            % Experimental Data
            for i = [11 13 14 15 16]
                nexttile();
                h = heatmap(squeeze(app.Data.CstData3D(:,:,i))');
                h.ColorbarVisible = 'off'; %h.XLabel = 'T (Days)'; %h.YLabel = 'Training Duration';
                h.FontSize = 8;
                h.Title = [Labels(i) ' T= ' num2str(i*2+2) ' ^oC'];
                h.XDisplayLabels(:) = {''}; h.YDisplayLabels(:) = {''};
                % h.XDisplayLabels([1 5 10 15 20 25]) = {'0', '5', '10', '15', '20', '25'};
                % h.YDisplayLabels([1 6 11 15]) = {'0', '5', '10', '14'};
                % survival = [survival sum(CstData3D(:,:,i)>0.1,2);];
            end
            % Model Data (without noise)
            for i = [11 13 14 15 16]
                nexttile();
                h = heatmap(squeeze(app.Model1Data.HealthTruncated(:,:,i))');
                h.ColorbarVisible = 'off'; %h.XLabel = 'T (Days)'; %h.YLabel = 'Training Duration';
                h.FontSize = 8;
                % h.Title = [Labels(i) ' T= ' num2str(i*2+2) ' ^oC (model)'];
                h.XDisplayLabels(:) = {''}; h.YDisplayLabels(:) = {''};
                % h.XDisplayLabels([1 5 10 15 20 25]) = {'0', '5', '10', '15', '20', '25'};
                % h.YDisplayLabels([1 6 11 15]) = {'0', '5', '10', '14'};
                % survival = [survival sum(CstData3D(:,:,i)>0.1,2);];
            end
            % Model Data (with noise)
            for i = [11 13 14 15 16]
                nexttile();
                h = heatmap(squeeze(app.Model2Data.HealthTruncated(:,:,i))');
                h.ColorbarVisible = 'off';
                h.FontSize = 8;
                % h.Title = [Labels(i) ' T= ' num2str(i*2+2) ' ^oC (model)'];
                h.XDisplayLabels(:) = {''}; h.YDisplayLabels(:) = {''};
                if i == 16
                    h.XDisplayLabels([1 5 10 15 20 25]) = {'0', '5', '10', '15', '20', '25'};
                    h.YDisplayLabels([1 6 11 15]) = {'0', '5', '10', '14'};
                    h.XLabel = 'T (Days)';
                    h.YLabel = 'Training Duration';
                end
                % survival = [survival sum(CstData3D(:,:,i)>0.1,2);];
            end
            colormap(app.Data.cmaplist.cmapRedBlue1)
        end

        % Value changed function: TimeresolutionSpinner
        function TimeresolutionSpinnerValueChanged(app, event)
            refresh(app)            
        end

        % Value changed function: NoiseAmplitudeSpinner
        function NoiseAmplitudeSpinnerValueChanged(app, event)
            refresh(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesPlot = uiaxes(app.UIFigure);
            title(app.UIAxesPlot, 'Acclimation')
            xlabel(app.UIAxesPlot, 'Testing time (Days)')
            ylabel(app.UIAxesPlot, 'Health, Acclimation, \theta')
            zlabel(app.UIAxesPlot, 'Z')
            app.HeatmapModel2 = uiaxes(app.UIFigure);
            title(app.HeatmapModel2, 'Model Data with noise')
            xlabel(app.HeatmapModel2, 'Training Duration (Days)')
            ylabel(app.HeatmapModel2, 'Training Temp (C)')
            zlabel(app.HeatmapModel2, 'Z')
            app.HeatmapModel1 = uiaxes(app.UIFigure);
            title(app.HeatmapModel1, 'Model Data without noise')
            xlabel(app.HeatmapModel1, 'Training Duration (Days)')
            ylabel(app.HeatmapModel1, 'Training Temp (C)')
            zlabel(app.HeatmapModel1, 'Z')
            app.HeatmapExp = uiaxes(app.UIFigure);
            title(app.HeatmapExp, 'Experimental Data')
            xlabel(app.HeatmapExp, 'Training duration (Days)')
            ylabel(app.HeatmapExp, 'Training Temp (C)')
            zlabel(app.HeatmapExp, 'Z')
            app.HeatmapDeAcModel2 = uiaxes(app.UIFigure);
            title(app.HeatmapDeAcModel2, 'Deaccl - Model Data with noise')
            xlabel(app.HeatmapDeAcModel2, 'Testing Time (Days)')
            ylabel(app.HeatmapDeAcModel2, 'Training Duratin (Days)')
            zlabel(app.HeatmapDeAcModel2, 'Z')
            app.HeatmapDeAcExpt = uiaxes(app.UIFigure);
            title(app.HeatmapDeAcExpt, 'Deaccl - Experimental Data')
            xlabel(app.HeatmapDeAcExpt, 'Testing Time (Days)')
            ylabel(app.HeatmapDeAcExpt, 'Traininig Duration (Days)')
            zlabel(app.HeatmapDeAcExpt, 'Z')
            app.HeatmapDeAcModel1 = uiaxes(app.UIFigure);
            title(app.HeatmapDeAcModel1, 'Deaccl = Model Data without noise')
            xlabel(app.HeatmapDeAcModel1, 'Testing Time (Days)')
            ylabel(app.HeatmapDeAcModel1, 'Training Duration (Days)')
            zlabel(app.HeatmapDeAcModel1, 'Z')
            app.TrainingTempLabel = uilabel(app.UIFigure);
            app.TrainingTempLabel.Text = 'Training Temp';
            app.TrainingTempSpinner = uispinner(app.UIFigure);
            app.TrainingTempSpinner.Step = 2;
            app.TrainingTempSpinner.Limits = [4 34];
            app.TrainingTempSpinner.ValueChangedFcn = createCallbackFcn(app, @TrainingTempSpinnerValueChanged, true);
            app.TrainingTempSpinner.Value = 10;
            app.TrainingDurationSpinnerLabel = uilabel(app.UIFigure);
            app.TrainingDurationSpinnerLabel.Text = 'Training Duration';
            app.TrainingDurationSpinner = uispinner(app.UIFigure);
            app.TrainingDurationSpinner.Limits = [0 14];
            app.TrainingDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @TrainingDurationSpinnerValueChanged, true);
            app.TrainingDurationSpinner.Value = 14;
            app.ComputetotalerrorButton = uibutton(app.UIFigure, 'push');
            app.ComputetotalerrorButton.ButtonPushedFcn = createCallbackFcn(app, @ComputetotalerrorButtonPushed, true);
            app.ComputetotalerrorButton.Text = 'Compute total error';
            app.c1SpinnerLabel = uilabel(app.UIFigure);
            app.c1SpinnerLabel.Text = 'c1';
            app.c1Spinner = uispinner(app.UIFigure);
            app.c1Spinner.Step = 0.02;
            app.c1Spinner.Limits = [0 10];
            app.c1Spinner.ValueChangedFcn = createCallbackFcn(app, @c1SpinnerValueChanged, true);
            app.c1Spinner.Value = 0.37;
            app.c2SpinnerLabel = uilabel(app.UIFigure);
            app.c2SpinnerLabel.Text = 'c2';
            app.c2Spinner = uispinner(app.UIFigure);
            app.c2Spinner.Step = 0.02;
            app.c2Spinner.Limits = [0 10];
            app.c2Spinner.ValueChangedFcn = createCallbackFcn(app, @c2SpinnerValueChanged, true);
            app.c2Spinner.Value = 0.39;
            app.c3SpinnerLabel = uilabel(app.UIFigure);
            app.c3SpinnerLabel.Text = 'c3';
            app.c3Spinner = uispinner(app.UIFigure);
            app.c3Spinner.Step = 0.02;
            app.c3Spinner.Limits = [0 10];
            app.c3Spinner.ValueChangedFcn = createCallbackFcn(app, @c3SpinnerValueChanged, true);
            app.c3Spinner.Value = 0.13;
            app.PlotallHeatmapsButton = uibutton(app.UIFigure, 'push');
            app.PlotallHeatmapsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallHeatmapsButtonPushed, true);
            app.PlotallHeatmapsButton.Text = 'Plot all Heatmaps';
            app.TimeresolutionSpinnerLabel = uilabel(app.UIFigure);
            app.TimeresolutionSpinnerLabel.Text = 'Time resolution';
            app.TimeresolutionSpinner = uispinner(app.UIFigure);
            app.TimeresolutionSpinner.Limits = [1 100];
            app.TimeresolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeresolutionSpinnerValueChanged, true);
            app.TimeresolutionSpinner.Value = 10;
            app.NoiseAmplitudeSpinnerLabel = uilabel(app.UIFigure);
            app.NoiseAmplitudeSpinnerLabel.Text = 'Noise Amplitude';
            app.NoiseAmplitudeSpinner = uispinner(app.UIFigure);
            app.NoiseAmplitudeSpinner.Limits = [0 10];
            app.NoiseAmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @NoiseAmplitudeSpinnerValueChanged, true);
            app.NoiseAmplitudeSpinner.Value = 1.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIAcclimation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end